classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TotaltimeEditField          matlab.ui.control.EditField
        TotaltimeEditFieldLabel     matlab.ui.control.Label
        PlotdataCheckBox            matlab.ui.control.CheckBox
        AvailableportsListBox       matlab.ui.control.ListBox
        AvailableportsListBoxLabel  matlab.ui.control.Label
        DatalossLamp                matlab.ui.control.Lamp
        DatalossLampLabel           matlab.ui.control.Label
        PilotEditField              matlab.ui.control.EditField
        PilotEditFieldLabel         matlab.ui.control.Label
        OnOffSwitch                 matlab.ui.control.Switch
        BREditField_3               matlab.ui.control.NumericEditField
        BREditField_3Label          matlab.ui.control.Label
        BLEditField_3               matlab.ui.control.NumericEditField
        BLEditField_3Label          matlab.ui.control.Label
        FREditField_3               matlab.ui.control.NumericEditField
        FREditField_3Label          matlab.ui.control.Label
        FLEditField_3               matlab.ui.control.NumericEditField
        FLEditField_3Label          matlab.ui.control.Label
        TiretemperaturecLabel       matlab.ui.control.Label
        BREditField_2               matlab.ui.control.NumericEditField
        BREditField_2Label          matlab.ui.control.Label
        BLEditField_2               matlab.ui.control.NumericEditField
        BLEditField_2Label          matlab.ui.control.Label
        FREditField_2               matlab.ui.control.NumericEditField
        FREditField_2Label          matlab.ui.control.Label
        FLEditField_2               matlab.ui.control.NumericEditField
        FLEditField_2Label          matlab.ui.control.Label
        TirepressurepsiLabel        matlab.ui.control.Label
        SaveButton                  matlab.ui.control.Button
        LapButton                   matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        BREditField                 matlab.ui.control.NumericEditField
        BREditFieldLabel            matlab.ui.control.Label
        BLEditField                 matlab.ui.control.NumericEditField
        BLEditFieldLabel            matlab.ui.control.Label
        FREditField                 matlab.ui.control.NumericEditField
        FREditFieldLabel            matlab.ui.control.Label
        FLEditField                 matlab.ui.control.NumericEditField
        FLEditFieldLabel            matlab.ui.control.Label
        LapsEditField               matlab.ui.control.NumericEditField
        LapsEditFieldLabel          matlab.ui.control.Label
        TotalkmEditField_2          matlab.ui.control.NumericEditField
        TotalkmEditField_2Label     matlab.ui.control.Label
        CVTRPMGauge                 matlab.ui.control.Gauge
        CVTRPMGaugeLabel            matlab.ui.control.Label
        SpeedkmhGauge               matlab.ui.control.Gauge
        SpeedkmhGaugeLabel          matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        TripdataLabel               matlab.ui.control.Label
        RearGauge                   matlab.ui.control.LinearGauge
        RearGaugeLabel              matlab.ui.control.Label
        FrontGauge                  matlab.ui.control.LinearGauge
        FrontGaugeLabel             matlab.ui.control.Label
        BrakepressurePSILabel       matlab.ui.control.Label
        BRGauge                     matlab.ui.control.LinearGauge
        BRGaugeLabel                matlab.ui.control.Label
        BLGauge                     matlab.ui.control.LinearGauge
        BLGauge_3Label              matlab.ui.control.Label
        FRGauge                     matlab.ui.control.LinearGauge
        FRGaugeLabel                matlab.ui.control.Label
        FLGaugeLabel                matlab.ui.control.Label
        WheelSpeedkmhLabel          matlab.ui.control.Label
        SteeringangledgrGaugeLabel  matlab.ui.control.Label
        FLGauge                     matlab.ui.control.LinearGauge
        SteeringangledgrGauge       matlab.ui.control.SemicircularGauge
        CVTTemperaturecLabel        matlab.ui.control.Label
        EngineTemperaturecLabel     matlab.ui.control.Label
        EngineRPMGaugeLabel         matlab.ui.control.Label
        SuspensionpositionLabel     matlab.ui.control.Label
        CVTTemperaturecGauge        matlab.ui.control.NinetyDegreeGauge
        EngineTemperaturecGauge     matlab.ui.control.NinetyDegreeGauge
        EngineRPMGauge              matlab.ui.control.Gauge
    end

    properties (Access = public)
        lapCounter = 1;
        kmLap = 0;
        t1 = datetime;
        t2 = datetime;
        lapTimeIni = datetime;
        totalTime;
        pilot = 'null'
        tableData = {int8(1),0,0,0,'null'};

        espObj;
        remain = [];
        data1 = [];
        data2 = [];
        data3 = [];
        data4 = [];
        data5 = [];

        Sample_Index = 1;
        Animated_Plot;

        mytimer;
        OnOffStatus = 'Off';
        portAvailable;

        fileDataName = 'data.xls';
        fileLapDataName = 'lapData.xls';
        fmt = "dd MMMM yyyy, hh-mm-ss";


    end


    methods (Access = public)
        function [] = updateData(app,~,~)
            if (app.OnOffStatus == "On")
                try
                    %%test esp serial
                    flush(app.espObj);
                    str = readline(app.espObj);
                    %%str = extractAfter(str,3);
                    temp = [str2double(split(str,','))]; 
                    app.data1 = [app.data1;temp(1)];
                    app.data2 = [app.data2;temp(2)];
                    app.data3 = [app.data3;temp(3)];
                    app.data4 = [app.data4;temp(4)];
                    app.data5 = [app.data5;temp(5)];

                    %disp(app.int1)
                    app.FrontGauge.Value = app.data1(end);
                    app.RearGauge.Value = app.data2(end);

                    app.DatalossLamp.Visible = "off";
                    app.DatalossLampLabel.Visible = "off";

                    % update the dashboard
                    app.TotaltimeEditField.Value = string(datetime - app.lapTimeIni);


                    if(app.PlotdataCheckBox.Value == 1)    
                        player = geoplayer(app.data3(end),app.data4(end));
                        %plotRoute(player,data.latitude,data.longitude);
                        plotPosition(player,app.data3(end),app.data4(end));


                        % % %Add points to the plot% hold on
                        % addpoints(app.Animated_Plot, app.Sample_Index,
                        % temp(1)); drawnow
                        % 
                        % %Window is the number of samples on screen/figure
                        % at a time% %Window = 50; %Sample_Chunk =
                        % floor(Sample_Index/Window); %Maximum_Amplitude =
                        % 1; %Minimum_Amplitude = -1.5;
                        % %axis([Sample_Chunk*Window
                        % Sample_Chunk*Window+Window Minimum_Amplitude
                        % Maximum_Amplitude]); title("Shock absorber
                        % level"); xlabel("Sample Index");
                        % ylabel("Amplitude"); app.Sample_Index =
                        % app.Sample_Index + 1; grid on %

                    end

                catch
                    warning('Problem whit some data incorrect');
                    app.DatalossLamp.Visible = "on";
                    app.DatalossLampLabel.Visible = "on";

                end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: LapButton
        function LapButtonPushed(app, event)
            app.lapCounter = app.lapCounter + int8(1);
            app.t2 = datetime ;
            dt = app.t2-app.t1;
            app.totalTime = app.t2 - app.lapTimeIni; 
            app.pilot = app.PilotEditField;
            app.tableData = [app.tableData;
                {int8(app.lapCounter), ...
                fix(minutes(dt)), ...
                mod(seconds(dt),60), ...
                -1, ...
                app.PilotEditField.Value}];

            app.UITable.Data = app.tableData;
            app.t1 = app.t2;

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.t1 = datetime ;
            app.lapTimeIni = app.t1;
            app.totalTime = 0;
            app.pilot = 'null';
            app.lapCounter = 0;
            app.tableData = {int8(app.lapCounter), ...
                0, ...
                0, ...
                -1, ...
                app.pilot};
            app.UITable.Data = app.tableData;
        end

        % Value changed function: OnOffSwitch
        function OnOffSwitchValueChanged(app, event)
            app.OnOffStatus = app.OnOffSwitch.Value;

            if (app.OnOffStatus == "On")
                app.mytimer = timer('Period',0.1,'ExecutionMode','fixedRate');
                app.mytimer.TimerFcn = @app.updateData;
                % app.espObj = serialport("/dev/ttyACM0",115200);
                % app.espObj = serialport("/dev/ttyUSB0",115200);
                app.espObj = serialport(app.portAvailable,9600);
                flush(app.espObj)
                configureTerminator(app.espObj,"CR/LF");              
                start(app.mytimer);
            else
                %fclose(app.espObj);
                delete(app.espObj);
                %clear(app.espObj);
                stop(app.mytimer);
            end
        end

        % Clicked callback: AvailableportsListBox
        function AvailableportsListBoxClicked(app, event)
            app.AvailableportsListBox.Items = serialportlist("available");
        end

        % Double-clicked callback: AvailableportsListBox
        function AvailableportsListBoxDoubleClicked(app, event)
            app.portAvailable = app.AvailableportsListBox.Value;
        end

        % Value changed function: PlotdataCheckBox
        function PlotdataCheckBoxValueChanged(app, event)
            if(app.PlotdataCheckBox.Value == 1 && app.OnOffStatus == "On")
                app.Animated_Plot = animatedline;
                app.Sample_Index = 1;
            else
                close all;
                app.Sample_Index = 1;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            sheetName = string(datetime,app.fmt);
            app.tableData  = [app.data1 app.data2 app.data3];
            writematrix(app.tableData,app.fileDataName,'Sheet', sheetName);
            writecell(app.UITable.Data,app.fileLapDataName,'Sheet', sheetName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EngineRPMGauge = uigauge(app.UIFigure, 'circular');
            app.EngineRPMGauge.Limits = [0 1500];
            app.EngineTemperaturecGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.CVTTemperaturecGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.SuspensionpositionLabel = uilabel(app.UIFigure);
            app.SuspensionpositionLabel.Text = 'Suspension position.';
            app.EngineRPMGaugeLabel = uilabel(app.UIFigure);
            app.EngineRPMGaugeLabel.Text = 'Engine RPM';
            app.EngineTemperaturecLabel = uilabel(app.UIFigure);
            app.EngineTemperaturecLabel.Text = 'Engine Temperature. [°c]';
            app.CVTTemperaturecLabel = uilabel(app.UIFigure);
            app.CVTTemperaturecLabel.Text = 'CVT Temperature. [°c]';
            app.SteeringangledgrGauge = uigauge(app.UIFigure, 'semicircular');
            app.SteeringangledgrGauge.Limits = [-70 70];
            app.FLGauge = uigauge(app.UIFigure, 'linear');
            app.SteeringangledgrGaugeLabel = uilabel(app.UIFigure);
            app.SteeringangledgrGaugeLabel.Text = 'Steering angle. [dgr]';
            app.WheelSpeedkmhLabel = uilabel(app.UIFigure);
            app.WheelSpeedkmhLabel.Text = 'Wheel Speed. [km/h]';
            app.FLGaugeLabel = uilabel(app.UIFigure);
            app.FLGaugeLabel.Text = 'FL';
            app.FRGaugeLabel = uilabel(app.UIFigure);
            app.FRGaugeLabel.Text = 'FR';
            app.FRGauge = uigauge(app.UIFigure, 'linear');
            app.BLGauge_3Label = uilabel(app.UIFigure);
            app.BLGauge_3Label.Text = 'BL';
            app.BLGauge = uigauge(app.UIFigure, 'linear');
            app.BRGaugeLabel = uilabel(app.UIFigure);
            app.BRGaugeLabel.Text = 'BR';
            app.BRGauge = uigauge(app.UIFigure, 'linear');
            app.BrakepressurePSILabel = uilabel(app.UIFigure);
            app.BrakepressurePSILabel.Text = 'Brake pressure. [PSI]';
            app.FrontGaugeLabel = uilabel(app.UIFigure);
            app.FrontGaugeLabel.Text = 'Front';
            app.FrontGauge = uigauge(app.UIFigure, 'linear');
            app.FrontGauge.Limits = [0 1500];
            app.RearGaugeLabel = uilabel(app.UIFigure);
            app.RearGaugeLabel.Text = 'Rear';
            app.RearGauge = uigauge(app.UIFigure, 'linear');
            app.RearGauge.Limits = [0 1500];
            app.TripdataLabel = uilabel(app.UIFigure);
            app.TripdataLabel.Text = 'Trip data';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Lap'; 't [min]'; 't [sec]'; 'km'; 'Pilot'};
            app.UITable.RowName = {};
            app.UITable.Multiselect = 'off';
            app.SpeedkmhGaugeLabel = uilabel(app.UIFigure);
            app.SpeedkmhGaugeLabel.Text = 'Speed. [km/h]';
            app.SpeedkmhGauge = uigauge(app.UIFigure, 'circular');
            app.SpeedkmhGauge.Limits = [0 50];
            app.CVTRPMGaugeLabel = uilabel(app.UIFigure);
            app.CVTRPMGaugeLabel.Text = 'CVT RPM';
            app.CVTRPMGauge = uigauge(app.UIFigure, 'circular');
            app.CVTRPMGauge.Limits = [0 1500];
            app.TotalkmEditField_2Label = uilabel(app.UIFigure);
            app.TotalkmEditField_2Label.Text = 'Total. [km]';
            app.TotalkmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LapsEditFieldLabel = uilabel(app.UIFigure);
            app.LapsEditFieldLabel.Text = 'Laps';
            app.LapsEditField = uieditfield(app.UIFigure, 'numeric');
            app.FLEditFieldLabel = uilabel(app.UIFigure);
            app.FLEditFieldLabel.Text = 'FL';
            app.FLEditField = uieditfield(app.UIFigure, 'numeric');
            app.FREditFieldLabel = uilabel(app.UIFigure);
            app.FREditFieldLabel.Text = 'FR';
            app.FREditField = uieditfield(app.UIFigure, 'numeric');
            app.BLEditFieldLabel = uilabel(app.UIFigure);
            app.BLEditFieldLabel.Text = 'BL';
            app.BLEditField = uieditfield(app.UIFigure, 'numeric');
            app.BREditFieldLabel = uilabel(app.UIFigure);
            app.BREditFieldLabel.Text = 'BR';
            app.BREditField = uieditfield(app.UIFigure, 'numeric');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LapButton = uibutton(app.UIFigure, 'push');
            app.LapButton.ButtonPushedFcn = createCallbackFcn(app, @LapButtonPushed, true);
            app.LapButton.Text = 'Lap';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.TirepressurepsiLabel = uilabel(app.UIFigure);
            app.TirepressurepsiLabel.Text = 'Tire pressure. [psi]';
            app.FLEditField_2Label = uilabel(app.UIFigure);
            app.FLEditField_2Label.Text = 'FL';
            app.FLEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FREditField_2Label = uilabel(app.UIFigure);
            app.FREditField_2Label.Text = 'FR';
            app.FREditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.BLEditField_2Label = uilabel(app.UIFigure);
            app.BLEditField_2Label.Text = 'BL';
            app.BLEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.BREditField_2Label = uilabel(app.UIFigure);
            app.BREditField_2Label.Text = 'BR';
            app.BREditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TiretemperaturecLabel = uilabel(app.UIFigure);
            app.TiretemperaturecLabel.Text = 'Tire temperature. [°c]';
            app.FLEditField_3Label = uilabel(app.UIFigure);
            app.FLEditField_3Label.Text = 'FL';
            app.FLEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.FREditField_3Label = uilabel(app.UIFigure);
            app.FREditField_3Label.Text = 'FR';
            app.FREditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.BLEditField_3Label = uilabel(app.UIFigure);
            app.BLEditField_3Label.Text = 'BL';
            app.BLEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.BREditField_3Label = uilabel(app.UIFigure);
            app.BREditField_3Label.Text = 'BR';
            app.BREditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.OnOffSwitch = uiswitch(app.UIFigure, 'slider');
            app.OnOffSwitch.ValueChangedFcn = createCallbackFcn(app, @OnOffSwitchValueChanged, true);
            app.PilotEditFieldLabel = uilabel(app.UIFigure);
            app.PilotEditFieldLabel.Text = 'Pilot';
            app.PilotEditField = uieditfield(app.UIFigure, 'text');
            app.PilotEditField.Value = 'name';
            app.DatalossLampLabel = uilabel(app.UIFigure);
            app.DatalossLampLabel.Visible = 'off';
            app.DatalossLampLabel.Text = 'Data loss';
            app.DatalossLamp = uilamp(app.UIFigure);
            app.DatalossLamp.Visible = 'off';
            app.AvailableportsListBoxLabel = uilabel(app.UIFigure);
            app.AvailableportsListBoxLabel.Text = 'Available ports';
            app.AvailableportsListBox = uilistbox(app.UIFigure);
            app.AvailableportsListBox.Items = {};
            app.AvailableportsListBox.ClickedFcn = createCallbackFcn(app, @AvailableportsListBoxClicked, true);
            app.AvailableportsListBox.DoubleClickedFcn = createCallbackFcn(app, @AvailableportsListBoxDoubleClicked, true);
            app.AvailableportsListBox.Value = {};
            app.PlotdataCheckBox = uicheckbox(app.UIFigure);
            app.PlotdataCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotdataCheckBoxValueChanged, true);
            app.PlotdataCheckBox.Text = 'Plot data';
            app.TotaltimeEditFieldLabel = uilabel(app.UIFigure);
            app.TotaltimeEditFieldLabel.Text = 'Total  time';
            app.TotaltimeEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end